classdef BEST_Outbreak_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BESTOutbreakAppUIFigure      matlab.ui.Figure
        BESTRobotics2020OutbreakOnlineGameTemplatesLabel  matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        SkillsChallengePanel         matlab.ui.container.Panel
        KeyboardTemplateButton       matlab.ui.control.Button
        GamepadTemplateButton        matlab.ui.control.Button
        AutonomousChallengePanel     matlab.ui.container.Panel
        AutonomousProgrammingTemplateButton  matlab.ui.control.Button
        RobotModelingChallengePanel  matlab.ui.container.Panel
        TutorialButton               matlab.ui.control.Button
        ClawbotDemoButton            matlab.ui.control.Button
        GameInfoButton               matlab.ui.control.Button
        BESTRoboticsButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GameInfoButton
        function GameInfoButtonPushed(app, event)
            web('https://registry.bestrobotics.org/','-browser')
        end

        % Button pushed function: BESTRoboticsButton
        function BESTRoboticsButtonPushed(app, event)
            web('https://www.bestrobotics.org/','-browser')
        end

        % Button pushed function: KeyboardTemplateButton
        function KeyboardTemplateButtonPushed(app, event)
            open_system('BEST2020_keyboard_template')
        end

        % Button pushed function: GamepadTemplateButton
        function GamepadTemplateButtonPushed(app, event)
            open_system('BEST2020_gamepad_template')
        end

        % Button pushed function: 
        % AutonomousProgrammingTemplateButton
        function AutonomousProgrammingTemplateButtonPushed(app, event)
            open_system('BEST2020_autonomous_template')
        end

        % Button pushed function: TutorialButton
        function TutorialButtonPushed(app, event)
            web('https://blogs.mathworks.com/racing-lounge/2020/08/18/creating-virtual-robot-environments-in-simscape-part-1/','-browser')
        end

        % Button pushed function: ClawbotDemoButton
        function ClawbotDemoButtonPushed(app, event)
            open_system('RP_ClawbotSim_teleop')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BESTOutbreakAppUIFigure = uifigure('Visible', 'off');
            app.BESTOutbreakAppUIFigure.Name = 'BEST Robotics - Outbreak App';
            app.BESTRobotics2020OutbreakOnlineGameTemplatesLabel = uilabel(app.BESTOutbreakAppUIFigure);
            app.BESTRobotics2020OutbreakOnlineGameTemplatesLabel.Text = {'BEST Robotics 2020 - Outbreak'; 'Online Game Templates'};
            app.Button = uibutton(app.BESTOutbreakAppUIFigure, 'push');
            app.Button.Icon = 'OutbreakLogo.png';
            app.Button.Text = '';
            app.SkillsChallengePanel = uipanel(app.BESTOutbreakAppUIFigure);
            app.SkillsChallengePanel.Title = 'Skills Challenge';
            app.KeyboardTemplateButton = uibutton(app.SkillsChallengePanel, 'push');
            app.KeyboardTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardTemplateButtonPushed, true);
            app.KeyboardTemplateButton.Text = 'Keyboard Template';
            app.GamepadTemplateButton = uibutton(app.SkillsChallengePanel, 'push');
            app.GamepadTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @GamepadTemplateButtonPushed, true);
            app.GamepadTemplateButton.Text = 'Gamepad Template';
            app.AutonomousChallengePanel = uipanel(app.BESTOutbreakAppUIFigure);
            app.AutonomousChallengePanel.Title = 'Autonomous Challenge';
            app.AutonomousProgrammingTemplateButton = uibutton(app.AutonomousChallengePanel, 'push');
            app.AutonomousProgrammingTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @AutonomousProgrammingTemplateButtonPushed, true);
            app.AutonomousProgrammingTemplateButton.Text = 'Autonomous Programming Template';
            app.RobotModelingChallengePanel = uipanel(app.BESTOutbreakAppUIFigure);
            app.RobotModelingChallengePanel.Title = 'Robot Modeling Challenge';
            app.TutorialButton = uibutton(app.RobotModelingChallengePanel, 'push');
            app.TutorialButton.ButtonPushedFcn = createCallbackFcn(app, @TutorialButtonPushed, true);
            app.TutorialButton.Text = 'Tutorial';
            app.ClawbotDemoButton = uibutton(app.RobotModelingChallengePanel, 'push');
            app.ClawbotDemoButton.ButtonPushedFcn = createCallbackFcn(app, @ClawbotDemoButtonPushed, true);
            app.ClawbotDemoButton.Text = 'Clawbot Demo';
            app.GameInfoButton = uibutton(app.BESTOutbreakAppUIFigure, 'push');
            app.GameInfoButton.ButtonPushedFcn = createCallbackFcn(app, @GameInfoButtonPushed, true);
            app.GameInfoButton.Text = 'Game Info';
            app.BESTRoboticsButton = uibutton(app.BESTOutbreakAppUIFigure, 'push');
            app.BESTRoboticsButton.ButtonPushedFcn = createCallbackFcn(app, @BESTRoboticsButtonPushed, true);
            app.BESTRoboticsButton.Text = 'BEST Robotics';
            % Show the figure after all components are created
            app.BESTOutbreakAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEST_Outbreak_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BESTOutbreakAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BESTOutbreakAppUIFigure)
        end
    end
end