classdef RP_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoboticsPlaygroundAppUIFigure  matlab.ui.Figure
        OpenLibraryButton              matlab.ui.control.Button
        BrowseAllExamplesButton        matlab.ui.control.Button
        DocumentationButton            matlab.ui.control.Button
        MATLABandSimulinkRoboticsPlaygroundLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        GettingStartedExamplesPanel    matlab.ui.container.Panel
        ObstacleEnvironmentButton      matlab.ui.control.Button
        ObjectEnvironmentButton        matlab.ui.control.Button
        MATLABRobotButton              matlab.ui.control.Button
        SumoGameButton                 matlab.ui.control.Button
        ClawbotSimulationsButton       matlab.ui.control.Button
        BESTRobotics                   matlab.ui.control.Button
        ContactUsButton                matlab.ui.control.Button
        LessonsButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenLibraryButton
        function OpenLibraryButtonPushed(app, event)
            open_system('RoboticsPlayground')
        end

        % Button pushed function: BrowseAllExamplesButton
        function BrowseAllExamplesButtonPushed(app, event)
            rp_dir=fileparts(which('RoboticsPlayground'));
            cd(replace(rp_dir,'lib','examples'));
            commandwindow
        end

        % Button pushed function: DocumentationButton
        function DocumentationButtonPushed(app, event)
            rp_dir=fileparts(which('RoboticsPlayground'));
            helpview(fullfile(replace(rp_dir,'lib','doc'),'html','GettingStarted.html'));
        end

        % Button pushed function: ObstacleEnvironmentButton
        function ObstacleEnvironmentButtonPushed(app, event)
            open_system('RP_Obstacle_Environment_Dashboard')
        end

        % Button pushed function: ObjectEnvironmentButton
        function ObjectEnvironmentButtonPushed(app, event)
            open_system('RP_Object_Environment_Dashboard')
        end

        % Button pushed function: MATLABRobotButton
        function MATLABRobotButtonPushed(app, event)
             evalin('base','edit mlrobot_getting_started');
             commandwindow
        end

        % Button pushed function: SumoGameButton
        function SumoGameButtonPushed(app, event)
             open_system('RP_Sumo_Dashboard')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            xpbombs;
        end

        % Button pushed function: ContactUsButton
        function ContactUsButtonPushed(app, event)
            msgbox('Contact us at roboticsarena@mathworks.com');
        end

        % Button pushed function: LessonsButton
        function LessonsButtonPushed(app, event)
            rp_dir=fileparts(which('RoboticsPlayground'));
            cd(fullfile(replace(rp_dir,'lib','examples'),'lessons'));
            commandwindow
        end

        % Button pushed function: ClawbotSimulationsButton
        function ClawbotSimulationsButtonPushed(app, event)
            open_system('RP_ClawbotSim_auto')
        end

        % Button pushed function: BESTRobotics
        function BESTRoboticsButtonPushed(app, event)
            matlab.apputil.run('BESTRoboticsOutbreakAPP');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RoboticsPlaygroundAppUIFigure = uifigure('Visible', 'off');
            app.RoboticsPlaygroundAppUIFigure.Name = 'Robotics Playground App';
            app.OpenLibraryButton = uibutton(app.RoboticsPlaygroundAppUIFigure, 'push');
            app.OpenLibraryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLibraryButtonPushed, true);
            app.OpenLibraryButton.Text = 'Open Library';
            app.BrowseAllExamplesButton = uibutton(app.RoboticsPlaygroundAppUIFigure, 'push');
            app.BrowseAllExamplesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseAllExamplesButtonPushed, true);
            app.BrowseAllExamplesButton.Text = 'Browse All Examples';
            app.DocumentationButton = uibutton(app.RoboticsPlaygroundAppUIFigure, 'push');
            app.DocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @DocumentationButtonPushed, true);
            app.DocumentationButton.Text = 'Documentation';
            app.MATLABandSimulinkRoboticsPlaygroundLabel = uilabel(app.RoboticsPlaygroundAppUIFigure);
            app.MATLABandSimulinkRoboticsPlaygroundLabel.Text = {'MATLAB and Simulink'; 'Robotics Playground'};
            app.Button = uibutton(app.RoboticsPlaygroundAppUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'RP_icon.PNG';
            app.Button.Text = '';
            app.GettingStartedExamplesPanel = uipanel(app.RoboticsPlaygroundAppUIFigure);
            app.GettingStartedExamplesPanel.Title = 'Getting Started Examples';
            app.ObstacleEnvironmentButton = uibutton(app.GettingStartedExamplesPanel, 'push');
            app.ObstacleEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @ObstacleEnvironmentButtonPushed, true);
            app.ObstacleEnvironmentButton.Text = 'Obstacle Environment';
            app.ObjectEnvironmentButton = uibutton(app.GettingStartedExamplesPanel, 'push');
            app.ObjectEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectEnvironmentButtonPushed, true);
            app.ObjectEnvironmentButton.Text = 'Object Environment';
            app.MATLABRobotButton = uibutton(app.GettingStartedExamplesPanel, 'push');
            app.MATLABRobotButton.ButtonPushedFcn = createCallbackFcn(app, @MATLABRobotButtonPushed, true);
            app.MATLABRobotButton.Text = 'MATLAB Robot';
            app.SumoGameButton = uibutton(app.GettingStartedExamplesPanel, 'push');
            app.SumoGameButton.ButtonPushedFcn = createCallbackFcn(app, @SumoGameButtonPushed, true);
            app.SumoGameButton.Text = 'Sumo Game';
            app.ClawbotSimulationsButton = uibutton(app.GettingStartedExamplesPanel, 'push');
            app.ClawbotSimulationsButton.ButtonPushedFcn = createCallbackFcn(app, @ClawbotSimulationsButtonPushed, true);
            app.ClawbotSimulationsButton.Icon = 'ClawbotIcon.png';
            app.ClawbotSimulationsButton.Text = {'Clawbot'; 'Simulations'};
            app.BESTRobotics = uibutton(app.GettingStartedExamplesPanel, 'push');
            app.BESTRobotics.ButtonPushedFcn = createCallbackFcn(app, @BESTRoboticsButtonPushed, true);
            app.BESTRobotics.Icon = 'BESTRobotics_Usage_Logo.jpg';
            app.BESTRobotics.Text = {'BEST Robotics'; 'Online 2020'};
            app.ContactUsButton = uibutton(app.RoboticsPlaygroundAppUIFigure, 'push');
            app.ContactUsButton.ButtonPushedFcn = createCallbackFcn(app, @ContactUsButtonPushed, true);
            app.ContactUsButton.Text = 'Contact Us';
            app.LessonsButton = uibutton(app.RoboticsPlaygroundAppUIFigure, 'push');
            app.LessonsButton.ButtonPushedFcn = createCallbackFcn(app, @LessonsButtonPushed, true);
            app.LessonsButton.Text = 'Lessons';
            % Show the figure after all components are created
            app.RoboticsPlaygroundAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RP_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoboticsPlaygroundAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoboticsPlaygroundAppUIFigure)
        end
    end
end