classdef VEXCompanionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VEXCompanion                 matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        VEXV5Tab                     matlab.ui.container.Tab
        InstallsupportforVEXEDRV5RobotBrainButton  matlab.ui.control.Button
        v5Icon                       matlab.ui.control.Button
        NewV5ModelButton             matlab.ui.control.Button
        OpenV5LibraryButton          matlab.ui.control.Button
        V5DemosButton                matlab.ui.control.Button
        V5DocumentationButton        matlab.ui.control.Button
        V5ExamplesListBoxLabel       matlab.ui.control.Label
        V5ExamplesListBox            matlab.ui.control.ListBox
        OpenExampleButtonV5          matlab.ui.control.Button
        VEXCortexTab                 matlab.ui.container.Tab
        CortexExamplesListBoxLabel   matlab.ui.control.Label
        CortexExamplesListBox        matlab.ui.control.ListBox
        NewCortexModelButton         matlab.ui.control.Button
        OpenCortexLibraryButton      matlab.ui.control.Button
        CortexDemosButton            matlab.ui.control.Button
        CortexDocumentationButton    matlab.ui.control.Button
        InstallsupportforVEXCortexButton  matlab.ui.control.Button
        cortexIcon                   matlab.ui.control.Button
        OpenExampleButtonCortex      matlab.ui.control.Button
        VirtualWorldsTab             matlab.ui.container.Tab
        InstallRoboticsPlaygroundButton  matlab.ui.control.Button
        rpIcon                       matlab.ui.control.Button
        OpenLibraryButton            matlab.ui.control.Button
        AdditionalExamplesButton     matlab.ui.control.Button
        DocumentationButton          matlab.ui.control.Button
        PlaygroundExamplesLabel      matlab.ui.control.Label
        PlaygroundExamplesListBox    matlab.ui.control.ListBox
        PlaygroundOpenExampleButton  matlab.ui.control.Button
        LessonsButton                matlab.ui.control.Button
        LearningResourcesTab         matlab.ui.container.Tab
        Button                       matlab.ui.control.Button
        VideoTutorialsButton         matlab.ui.control.Button
        FacebookForumButton          matlab.ui.control.Button
        TrainingButton               matlab.ui.control.Button
        CurriculumButton             matlab.ui.control.Button
        MoreStudentResourcesPanel    matlab.ui.container.Panel
        MathWorksStudentCompetitionPagesButton  matlab.ui.control.Button
        VEXCompanionAppLabel         matlab.ui.control.Label
        FAQButton                    matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        % URL's
        cortex_URL='https://www.mathworks.com/hardware-support/vex-arm-cortex.html';
        v5_URL='https://www.mathworks.com/hardware-support/vex-arm-cortex.html';
        playground_URL='https://www.mathworks.com/matlabcentral/fileexchange/67157-robotics-playground';
        mspssch_URL='https://www.mathworks.com/videos/series/matlab-and-simulink-pass-competitions-hub.html';
        facebook_URL='https://www.facebook.com/groups/PASScompetitions/';
        curriculum_URL='https://www.mathworks.com/academia/highschool/courseware.html';
        roboticsTraining_URL='https://www.mathworks.com/academia/student-competitions/mobile-robotics-training.html';
        studentCompetitions_URL='https://www.mathworks.com/academia/student-competitions.html';
        faq_URL='http://www.mathworks.com/support/search_results.html?q=tag:"vex robotics"';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            info = matlabshared.supportpkg.getInstalled;
            % Check that spkg for VEX Cortex is installed
            if (any(arrayfun(@(sp)strcmp(sp.Name, 'Simulink Coder Support Package for ARM Cortex-based VEX Microcontroller'), info)))
                % Enable all Buttons in Cortex tab
                
                app.NewCortexModelButton.Enable = 'on';
                app.NewCortexModelButton.Visible = 'on';
                app.OpenCortexLibraryButton.Enable = 'on';
                app.OpenCortexLibraryButton.Visible = 'on';
                app.CortexDemosButton.Enable = 'on';
                app.CortexDemosButton.Visible = 'on';
                app.CortexDocumentationButton.Enable = 'on';
                app.CortexDocumentationButton.Visible = 'on';
                app.CortexExamplesListBox.Enable = 'on';
                app.CortexExamplesListBox.Visible = 'on';
                app.InstallsupportforVEXCortexButton.Enable = 'off';
                app.InstallsupportforVEXCortexButton.Visible = 'off';
                app.OpenExampleButtonCortex.Enable = 'on';
                app.OpenExampleButtonCortex.Visible = 'on';
                app.CortexExamplesListBox.Items = vexapp.getAdditionalExampleCell('cortex');
            end
            % Check that spkg for VEX V5 is installed
            if (any(arrayfun(@(sp)strcmp(sp.Name, 'Simulink Coder Support Package for VEX EDR V5 Robot Brain'), info)))
                % V5 enable placing here for protoyping
                app.NewV5ModelButton.Enable = 'on';
                app.NewV5ModelButton.Visible = 'on';
                app.OpenV5LibraryButton.Enable = 'on';
                app.OpenV5LibraryButton.Visible = 'on';
                app.V5DemosButton.Enable = 'on';
                app.V5DemosButton.Visible = 'on';
                app.V5DocumentationButton.Enable = 'on';
                app.V5DocumentationButton.Visible = 'on';
                app.V5ExamplesListBox.Enable = 'on';
                app.V5ExamplesListBox.Visible = 'on';
                app.InstallsupportforVEXEDRV5RobotBrainButton.Enable = 'off';
                app.InstallsupportforVEXEDRV5RobotBrainButton.Visible = 'off';
                app.OpenExampleButtonV5.Enable = 'on';
                app.OpenExampleButtonV5.Visible = 'on';
                app.V5ExamplesListBox.Items = vexapp.getAdditionalExampleCell('vexv5');
            end
           
            
            % Check that Robotics Playground Toolbox is installed
            tb = matlab.addons.toolbox.installedToolboxes;
            if ~isempty(find(arrayfun(@(n)strcmp(tb(n).Name, 'Robotics Playground'), 1:numel(tb)),1))
                app.OpenLibraryButton.Enable = 'on';
                app.OpenLibraryButton.Visible = 'on';
                app.AdditionalExamplesButton.Enable = 'on';
                app.AdditionalExamplesButton.Visible = 'on';
                app.DocumentationButton.Enable = 'on';
                app.DocumentationButton.Visible = 'on';
                app.InstallRoboticsPlaygroundButton.Enable = 'off';
                app.InstallRoboticsPlaygroundButton.Visible = 'off';
                app.PlaygroundExamplesListBox.Enable='on';
                app.PlaygroundExamplesListBox.Visible='on';
                app.PlaygroundOpenExampleButton.Enable = 'on';
                app.PlaygroundOpenExampleButton.Visible = 'on';
                app.LessonsButton.Enable = 'on';
                app.LessonsButton.Visible = 'on';
                app.PlaygroundExamplesListBox.Items = vexapp.getAdditionalExampleCell('roboticsPlayground');
            end
            
            
        end

        % Button pushed function: InstallsupportforVEXCortexButton
        function InstallsupportforVEXCortexButtonPushed(app, event)
            web(app.cortex_URL,'-browser');
        end

        % Button pushed function: 
        % InstallsupportforVEXEDRV5RobotBrainButton
        function InstallsupportforVEXEDRV5RobotBrainButtonPushed(app, event)
            if ~verLessThan('matlab','9.5')
                web(app.v5_URL,'-browser');
            else
                msgbox('The VEX EDR V5 hardware support package for Simulink is not available for your current version of MATLAB. Please install MATLAB R2018b or later.');
            end
        end

        % Button pushed function: InstallRoboticsPlaygroundButton
        function InstallRoboticsPlaygroundButtonPushed(app, event)
            web(app.playground_URL,'-browser');
        end

        % Button pushed function: VideoTutorialsButton
        function VideoTutorialsButtonPushed(app, event)
            web(app.mspssch_URL,'-browser');
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)
            web(app.roboticsTraining_URL,'-browser');
        end

        % Button pushed function: FacebookForumButton
        function FacebookForumButtonPushed(app, event)
            web(app.facebook_URL,'-browser');
        end

        % Button pushed function: CurriculumButton
        function CurriculumButtonPushed(app, event)
            web(app.curriculum_URL,'-browser');
        end

        % Button pushed function: 
        % MathWorksStudentCompetitionPagesButton
        function MathWorksStudentCompetitionPagesButtonPushed(app, event)
            web(app.studentCompetitions_URL,'-browser');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web(app.mspssch_URL,'-browser');
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
             msgbox('Contact us at passcompetitions@mathworks.com');
        end

        % Button pushed function: FAQButton
        function FAQButtonPushed(app, event)
            web(app.faq_URL,'-browser');
        end

        % Button pushed function: AdditionalExamplesButton
        function AdditionalExamplesButtonPushed(app, event)
            rp_dir=fileparts(which('RoboticsPlayground'));
            cd(replace(rp_dir,'lib','examples'));
            commandwindow
        end

        % Button pushed function: OpenLibraryButton
        function OpenLibraryButtonPushed(app, event)
            vexapp.openLibrary('roboticsPlayground');
        end

        % Button pushed function: DocumentationButton
        function DocumentationButtonPushed(app, event)
             helpview(vexapp.getDocPath('roboticsPlayground'));
        end

        % Button pushed function: NewCortexModelButton
        function NewCortexModelButtonPushed(app, event)
            vexapp.createNewModel('cortex');
        end

        % Button pushed function: OpenCortexLibraryButton
        function OpenCortexLibraryButtonPushed(app, event)
            vexapp.openLibrary('cortex');
        end

        % Button pushed function: CortexDemosButton
        function CortexDemosButtonPushed(app, event)
            helpview(vexapp.getExamplesPath('cortex'));
        end

        % Button pushed function: CortexDocumentationButton
        function CortexDocumentationButtonPushed(app, event)
            helpview(vexapp.getDocPath('vexcortex'));
        end

        % Button pushed function: OpenExampleButtonCortex
        function OpenExampleButtonCortexPushed(app, event)
            exampleName=app.CortexExamplesListBox.Value;
            if ~isempty(exampleName)
                open_system(vexapp.getAdditionalExampleFullName(exampleName,'cortex'));
            end
        end

        % Button pushed function: NewV5ModelButton
        function NewV5ModelButtonPushed(app, event)
            vexapp.createNewModel('vexv5');
        end

        % Button pushed function: OpenV5LibraryButton
        function OpenV5LibraryButtonPushed(app, event)
            vexapp.openLibrary('vexv5');
        end

        % Button pushed function: V5DemosButton
        function V5DemosButtonPushed(app, event)
            helpview(vexapp.getExamplesPath('vexv5'));
        end

        % Button pushed function: PlaygroundOpenExampleButton
        function PlaygroundOpenExampleButtonPushed(app, event)
            exampleName=app.PlaygroundExamplesListBox.Value;
            if ~isempty(exampleName)
                open_system(vexapp.getAdditionalExampleFullName(exampleName,'roboticsPlayground'));
            end
        end

        % Button pushed function: OpenExampleButtonV5
        function OpenExampleButtonV5Pushed(app, event)
            exampleName=app.V5ExamplesListBox.Value;
            if ~isempty(exampleName)
                open_system(vexapp.getAdditionalExampleFullName(exampleName,'vexv5'));
            end
        end

        % Button pushed function: V5DocumentationButton
        function V5DocumentationButtonPushed(app, event)
            helpview(vexapp.getDocPath('vexv5'));
        end

        % Button pushed function: LessonsButton
        function LessonsButtonPushed(app, event)
            rp_dir=fileparts(which('RoboticsPlayground'));
            cd(fullfile(replace(rp_dir,'lib','examples'),'lessons'));
            commandwindow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VEXCompanion = uifigure('Visible', 'off');
            app.VEXCompanion.Name = 'VEX Companion';
            app.TabGroup = uitabgroup(app.VEXCompanion);
            app.VEXV5Tab = uitab(app.TabGroup);
            app.VEXV5Tab.Title = 'VEX V5';
            app.InstallsupportforVEXEDRV5RobotBrainButton = uibutton(app.VEXV5Tab, 'push');
            app.InstallsupportforVEXEDRV5RobotBrainButton.ButtonPushedFcn = createCallbackFcn(app, @InstallsupportforVEXEDRV5RobotBrainButtonPushed, true);
            app.InstallsupportforVEXEDRV5RobotBrainButton.Text = 'Install support for VEX EDR V5 Robot Brain';
            app.v5Icon = uibutton(app.VEXV5Tab, 'push');
            app.v5Icon.Icon = 'vex-v5-brain.png';
            app.v5Icon.Text = '';
            app.NewV5ModelButton = uibutton(app.VEXV5Tab, 'push');
            app.NewV5ModelButton.ButtonPushedFcn = createCallbackFcn(app, @NewV5ModelButtonPushed, true);
            app.NewV5ModelButton.Enable = 'off';
            app.NewV5ModelButton.Visible = 'off';
            app.NewV5ModelButton.Text = 'New V5 Model';
            app.OpenV5LibraryButton = uibutton(app.VEXV5Tab, 'push');
            app.OpenV5LibraryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenV5LibraryButtonPushed, true);
            app.OpenV5LibraryButton.Enable = 'off';
            app.OpenV5LibraryButton.Visible = 'off';
            app.OpenV5LibraryButton.Text = 'Open V5 Library';
            app.V5DemosButton = uibutton(app.VEXV5Tab, 'push');
            app.V5DemosButton.ButtonPushedFcn = createCallbackFcn(app, @V5DemosButtonPushed, true);
            app.V5DemosButton.Enable = 'off';
            app.V5DemosButton.Visible = 'off';
            app.V5DemosButton.Text = 'V5 Demos';
            app.V5DocumentationButton = uibutton(app.VEXV5Tab, 'push');
            app.V5DocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @V5DocumentationButtonPushed, true);
            app.V5DocumentationButton.Enable = 'off';
            app.V5DocumentationButton.Visible = 'off';
            app.V5DocumentationButton.Text = 'V5 Documentation';
            app.V5ExamplesListBoxLabel = uilabel(app.VEXV5Tab);
            app.V5ExamplesListBoxLabel.Enable = 'off';
            app.V5ExamplesListBoxLabel.Text = 'V5 Examples';
            app.V5ExamplesListBox = uilistbox(app.VEXV5Tab);
            app.V5ExamplesListBox.Items = {'Example 1', 'Example 2'};
            app.V5ExamplesListBox.Enable = 'off';
            app.V5ExamplesListBox.Visible = 'off';
            app.V5ExamplesListBox.Value = 'Example 2';
            app.OpenExampleButtonV5 = uibutton(app.VEXV5Tab, 'push');
            app.OpenExampleButtonV5.ButtonPushedFcn = createCallbackFcn(app, @OpenExampleButtonV5Pushed, true);
            app.OpenExampleButtonV5.Enable = 'off';
            app.OpenExampleButtonV5.Visible = 'off';
            app.OpenExampleButtonV5.Text = 'Open Example';
            app.VEXCortexTab = uitab(app.TabGroup);
            app.VEXCortexTab.Title = 'VEX Cortex';
            app.CortexExamplesListBoxLabel = uilabel(app.VEXCortexTab);
            app.CortexExamplesListBoxLabel.Enable = 'off';
            app.CortexExamplesListBoxLabel.Text = 'Cortex Examples';
            app.CortexExamplesListBox = uilistbox(app.VEXCortexTab);
            app.CortexExamplesListBox.Items = {};
            app.CortexExamplesListBox.Enable = 'off';
            app.CortexExamplesListBox.Visible = 'off';
            app.CortexExamplesListBox.Value = {};
            app.NewCortexModelButton = uibutton(app.VEXCortexTab, 'push');
            app.NewCortexModelButton.ButtonPushedFcn = createCallbackFcn(app, @NewCortexModelButtonPushed, true);
            app.NewCortexModelButton.Enable = 'off';
            app.NewCortexModelButton.Visible = 'off';
            app.NewCortexModelButton.Text = 'New Cortex Model';
            app.OpenCortexLibraryButton = uibutton(app.VEXCortexTab, 'push');
            app.OpenCortexLibraryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCortexLibraryButtonPushed, true);
            app.OpenCortexLibraryButton.Enable = 'off';
            app.OpenCortexLibraryButton.Visible = 'off';
            app.OpenCortexLibraryButton.Text = 'Open Cortex Library';
            app.CortexDemosButton = uibutton(app.VEXCortexTab, 'push');
            app.CortexDemosButton.ButtonPushedFcn = createCallbackFcn(app, @CortexDemosButtonPushed, true);
            app.CortexDemosButton.Enable = 'off';
            app.CortexDemosButton.Visible = 'off';
            app.CortexDemosButton.Text = 'Cortex Demos';
            app.CortexDocumentationButton = uibutton(app.VEXCortexTab, 'push');
            app.CortexDocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @CortexDocumentationButtonPushed, true);
            app.CortexDocumentationButton.Enable = 'off';
            app.CortexDocumentationButton.Visible = 'off';
            app.CortexDocumentationButton.Text = 'Cortex Documentation';
            app.InstallsupportforVEXCortexButton = uibutton(app.VEXCortexTab, 'push');
            app.InstallsupportforVEXCortexButton.ButtonPushedFcn = createCallbackFcn(app, @InstallsupportforVEXCortexButtonPushed, true);
            app.InstallsupportforVEXCortexButton.Text = 'Install support for VEX Cortex';
            app.cortexIcon = uibutton(app.VEXCortexTab, 'push');
            app.cortexIcon.Icon = 'vex-microcontroller.png';
            app.cortexIcon.Text = '';
            app.OpenExampleButtonCortex = uibutton(app.VEXCortexTab, 'push');
            app.OpenExampleButtonCortex.ButtonPushedFcn = createCallbackFcn(app, @OpenExampleButtonCortexPushed, true);
            app.OpenExampleButtonCortex.Enable = 'off';
            app.OpenExampleButtonCortex.Visible = 'off';
            app.OpenExampleButtonCortex.Text = 'Open Example';
            app.VirtualWorldsTab = uitab(app.TabGroup);
            app.VirtualWorldsTab.Title = 'Virtual Worlds';
            app.InstallRoboticsPlaygroundButton = uibutton(app.VirtualWorldsTab, 'push');
            app.InstallRoboticsPlaygroundButton.ButtonPushedFcn = createCallbackFcn(app, @InstallRoboticsPlaygroundButtonPushed, true);
            app.InstallRoboticsPlaygroundButton.Text = 'Install Robotics Playground';
            app.rpIcon = uibutton(app.VirtualWorldsTab, 'push');
            app.rpIcon.Icon = 'RP_icon.PNG';
            app.rpIcon.Text = '';
            app.OpenLibraryButton = uibutton(app.VirtualWorldsTab, 'push');
            app.OpenLibraryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLibraryButtonPushed, true);
            app.OpenLibraryButton.Enable = 'off';
            app.OpenLibraryButton.Visible = 'off';
            app.OpenLibraryButton.Text = 'Open Library';
            app.AdditionalExamplesButton = uibutton(app.VirtualWorldsTab, 'push');
            app.AdditionalExamplesButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionalExamplesButtonPushed, true);
            app.AdditionalExamplesButton.Enable = 'off';
            app.AdditionalExamplesButton.Visible = 'off';
            app.AdditionalExamplesButton.Text = 'Additional Examples';
            app.DocumentationButton = uibutton(app.VirtualWorldsTab, 'push');
            app.DocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @DocumentationButtonPushed, true);
            app.DocumentationButton.Enable = 'off';
            app.DocumentationButton.Visible = 'off';
            app.DocumentationButton.Text = 'Documentation';
            app.PlaygroundExamplesLabel = uilabel(app.VirtualWorldsTab);
            app.PlaygroundExamplesLabel.Enable = 'off';
            app.PlaygroundExamplesLabel.Text = {'Playground'; 'Examples'};
            app.PlaygroundExamplesListBox = uilistbox(app.VirtualWorldsTab);
            app.PlaygroundExamplesListBox.Items = {'Example 1', 'Example 2'};
            app.PlaygroundExamplesListBox.Enable = 'off';
            app.PlaygroundExamplesListBox.Visible = 'off';
            app.PlaygroundExamplesListBox.Value = 'Example 2';
            app.PlaygroundOpenExampleButton = uibutton(app.VirtualWorldsTab, 'push');
            app.PlaygroundOpenExampleButton.ButtonPushedFcn = createCallbackFcn(app, @PlaygroundOpenExampleButtonPushed, true);
            app.PlaygroundOpenExampleButton.Enable = 'off';
            app.PlaygroundOpenExampleButton.Visible = 'off';
            app.PlaygroundOpenExampleButton.Text = 'Open Example';
            app.LessonsButton = uibutton(app.VirtualWorldsTab, 'push');
            app.LessonsButton.ButtonPushedFcn = createCallbackFcn(app, @LessonsButtonPushed, true);
            app.LessonsButton.Enable = 'off';
            app.LessonsButton.Visible = 'off';
            app.LessonsButton.Text = 'Lessons';
            app.LearningResourcesTab = uitab(app.TabGroup);
            app.LearningResourcesTab.Title = 'Learning Resources';
            app.Button = uibutton(app.LearningResourcesTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'passcompetitions_hub_logo.jpg';
            app.Button.Text = '';
            app.VideoTutorialsButton = uibutton(app.LearningResourcesTab, 'push');
            app.VideoTutorialsButton.ButtonPushedFcn = createCallbackFcn(app, @VideoTutorialsButtonPushed, true);
            app.VideoTutorialsButton.Text = 'Video Tutorials';
            app.FacebookForumButton = uibutton(app.LearningResourcesTab, 'push');
            app.FacebookForumButton.ButtonPushedFcn = createCallbackFcn(app, @FacebookForumButtonPushed, true);
            app.FacebookForumButton.Text = 'Facebook Forum';
            app.TrainingButton = uibutton(app.LearningResourcesTab, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.CurriculumButton = uibutton(app.LearningResourcesTab, 'push');
            app.CurriculumButton.ButtonPushedFcn = createCallbackFcn(app, @CurriculumButtonPushed, true);
            app.CurriculumButton.Text = 'Curriculum';
            app.MoreStudentResourcesPanel = uipanel(app.LearningResourcesTab);
            app.MoreStudentResourcesPanel.Title = 'More Student Resources';
            app.MathWorksStudentCompetitionPagesButton = uibutton(app.MoreStudentResourcesPanel, 'push');
            app.MathWorksStudentCompetitionPagesButton.ButtonPushedFcn = createCallbackFcn(app, @MathWorksStudentCompetitionPagesButtonPushed, true);
            app.MathWorksStudentCompetitionPagesButton.Text = 'MathWorks Student Competition Pages';
            app.VEXCompanionAppLabel = uilabel(app.VEXCompanion);
            app.VEXCompanionAppLabel.Text = 'VEX Companion App';
            app.FAQButton = uibutton(app.VEXCompanion, 'push');
            app.FAQButton.ButtonPushedFcn = createCallbackFcn(app, @FAQButtonPushed, true);
            app.FAQButton.Text = 'FAQ';
            app.HelpButton = uibutton(app.VEXCompanion, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.VEXCompanion.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VEXCompanionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VEXCompanion)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VEXCompanion)
        end
    end
end